classdef FinalGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        PositionPanel          matlab.ui.container.Panel
        EndY                   matlab.ui.control.NumericEditField
        EndYLabel              matlab.ui.control.Label
        EndX                   matlab.ui.control.NumericEditField
        EndXLabel              matlab.ui.control.Label
        EndLabel               matlab.ui.control.Label
        StartYLabel            matlab.ui.control.Label
        StartXLabel            matlab.ui.control.Label
        StartX                 matlab.ui.control.NumericEditField
        StartY                 matlab.ui.control.NumericEditField
        StartLabel             matlab.ui.control.Label
        PathPlanButton         matlab.ui.control.Button
        MapSelection           matlab.ui.container.ButtonGroup
        TMap2txtButton         matlab.ui.control.RadioButton
        TMap1txtButton         matlab.ui.control.RadioButton
        OGridtxtButton         matlab.ui.control.RadioButton
        MapPanel               matlab.ui.container.Panel
        MapGrid                matlab.ui.container.GridLayout
        COMPortPanel           matlab.ui.container.Panel
        Baudrate               matlab.ui.control.DropDown
        BaudrateDropDownLabel  matlab.ui.control.Label
        ComPort                matlab.ui.control.Spinner
        ComPortSpinnerLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        Leo         % LEONARDO Robot
        map         % Text map of the enviroment
        mapImages   % Image map of the enviroment
        mapName     % Name of the Map
        
        pathingMap  % Text of map planning 
    end
    
    methods (Access = private)
        
        % Sets the Images of the Map
        function setMapGrid(app)
            app.map = importdata(app.mapName);
            app.MapGrid.Children.delete;
            for x = 1:size(app.map)
                for y = 1:size(app.map(:,1))
                    Image = uiimage(app.MapGrid);
                    Image.ImageSource = sprintf('Box%d.JPG',app.map(x,y));
                    app.mapImages(x+y-1) = Image;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.Leo = MobileGUI(app.ComPort.Value, str2double(app.Baudrate.Value));
            app.mapName = app.OGridtxtButton.Text;
            app.setMapGrid();
        end

        % Selection changed function: MapSelection
        function MapSelectionSelectionChanged(app, event)
            selectedButton = app.MapSelection.SelectedObject;
            app.mapName = selectedButton.Text;
            app.setMapGrid();
        end

        % Value changed function: Baudrate
        function BaudrateValueChanged(app, event)
            value = app.Baudrate.Value;
            app.Leo.setSerial(app.ComPort.Value,str2double(value));
        end

        % Value changed function: ComPort
        function ComPortValueChanged(app, event)
            value = app.ComPort.Value;
            app.Leo.setSerial(value,str2double(app.Baudrate.Value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.COMPortPanel = uipanel(app.UIFigure);
            app.COMPortPanel.Title = 'COM Port';
            app.ComPortSpinnerLabel = uilabel(app.COMPortPanel);
            app.ComPortSpinnerLabel.Text = 'Com Port';
            app.ComPort = uispinner(app.COMPortPanel);
            app.ComPort.Limits = [0 Inf];
            app.ComPort.ValueChangedFcn = createCallbackFcn(app, @ComPortValueChanged, true);
            app.ComPort.Value = 9;
            app.BaudrateDropDownLabel = uilabel(app.COMPortPanel);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.Baudrate = uidropdown(app.COMPortPanel);
            app.Baudrate.Items = {'300', '600', '1200', '2400', '4800', '9600', '14400', '19200', '28800', '38400', '57600', '115200', '230400', '250000'};
            app.Baudrate.ValueChangedFcn = createCallbackFcn(app, @BaudrateValueChanged, true);
            app.Baudrate.Value = '115200';
            app.MapPanel = uipanel(app.UIFigure);
            app.MapPanel.Title = 'Map';
            app.MapGrid = uigridlayout(app.MapPanel);
            app.MapSelection = uibuttongroup(app.UIFigure);
            app.MapSelection.SelectionChangedFcn = createCallbackFcn(app, @MapSelectionSelectionChanged, true);
            app.MapSelection.Title = 'Map Selection';
            app.OGridtxtButton = uiradiobutton(app.MapSelection);
            app.OGridtxtButton.Text = 'OGrid.txt';
            app.OGridtxtButton.Value = true;
            app.TMap1txtButton = uiradiobutton(app.MapSelection);
            app.TMap1txtButton.Text = 'TMap1.txt';
            app.TMap2txtButton = uiradiobutton(app.MapSelection);
            app.TMap2txtButton.Text = 'TMap2.txt';
            app.PositionPanel = uipanel(app.UIFigure);
            app.PositionPanel.Title = 'Position';
            app.PathPlanButton = uibutton(app.PositionPanel, 'push');
            app.PathPlanButton.Text = 'Path Plan';
            app.StartLabel = uilabel(app.PositionPanel);
            app.StartLabel.Text = 'Start';
            app.StartY = uieditfield(app.PositionPanel, 'numeric');
            app.StartX = uieditfield(app.PositionPanel, 'numeric');
            app.StartXLabel = uilabel(app.PositionPanel);
            app.StartXLabel.Text = 'X:';
            app.StartYLabel = uilabel(app.PositionPanel);
            app.StartYLabel.Text = 'Y:';
            app.EndLabel = uilabel(app.PositionPanel);
            app.EndLabel.Text = 'End';
            app.EndXLabel = uilabel(app.PositionPanel);
            app.EndXLabel.Text = 'X:';
            app.EndX = uieditfield(app.PositionPanel, 'numeric');
            app.EndYLabel = uilabel(app.PositionPanel);
            app.EndYLabel.Text = 'Y:';
            app.EndY = uieditfield(app.PositionPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end